classdef MOT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataLoaderTab                   matlab.ui.container.Tab
        DataPreviewPanel                matlab.ui.container.Panel
        ImageAxis                       matlab.ui.control.UIAxes
        DataLoadingSettingsPanel        matlab.ui.container.Panel
        LoadSingleFrameButton           matlab.ui.control.Button
        SingleFrame                     matlab.ui.control.NumericEditField
        ChoosenumbereddatafoldersetframerangethenclickloaddataLabel  matlab.ui.control.Label
        FileNameTemplate                matlab.ui.control.EditField
        FileNameTemplateEditFieldLabel  matlab.ui.control.Label
        UpperFrameRange                 matlab.ui.control.NumericEditField
        UpperFrameRangeEditFieldLabel   matlab.ui.control.Label
        LowerFrameRange                 matlab.ui.control.NumericEditField
        LowerFrameRangeEditFieldLabel   matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        SelectDataFolderButton          matlab.ui.control.Button
        CandidateDetectionTab           matlab.ui.container.Tab
        IdentifiedCandidatesPanel       matlab.ui.container.Panel
        CandidateAxis                   matlab.ui.control.UIAxes
        DataLoadingSettingsPanel_2      matlab.ui.container.Panel
        ClickbuttontoperformcandidatedetectionLabel  matlab.ui.control.Label
        DetectCandidatesButton          matlab.ui.control.Button
        CandidateDiscriminationTab      matlab.ui.container.Tab
        IdentifiedVehiclesPanel         matlab.ui.container.Panel
        DiscriminationAxis              matlab.ui.control.UIAxes
        SettingsPanel                   matlab.ui.container.Panel
        DiscriminationLabel             matlab.ui.control.Label
        EccentricityThresholdMax        matlab.ui.control.NumericEditField
        EccentricityThresholdMin        matlab.ui.control.NumericEditField
        ExxentricityLabel               matlab.ui.control.Label
        MajorAxisThresholdMax           matlab.ui.control.NumericEditField
        MajorAxisThresholdMin           matlab.ui.control.NumericEditField
        MajorAxisThresholdLabel         matlab.ui.control.Label
        ExtentThresholdMax              matlab.ui.control.NumericEditField
        ExtentThresholdMin              matlab.ui.control.NumericEditField
        ExtentThresholdLabel            matlab.ui.control.Label
        AreaThresholdMax                matlab.ui.control.NumericEditField
        AreaThresholdMin                matlab.ui.control.NumericEditField
        AreaLabel                       matlab.ui.control.Label
        IdentifyTrueCandidatesButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        fileLocation    % Path to the images that are to be loaded
        frameRange      % Range of images to load
        nameTemplate    % Naming scheme of image files
        frame           % When attempting to load a single frame
        csv             % Load trimmed gt.txt data
        p               % parser
        bin             % Output of candidateDetction function
        gryimg          % Output of candidateDetction function
        discriminants;  % Output of candidateDiscrimination function
        centroid;       % Vehicle centroids
        bbox;           % Vehicle bounding boxes
        DataLoaded;     % Indicator to check if dataset is loaded or not
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDataFolderButton
        function SelectDataFolderButtonPushed(app, event)
             app.fileLocation = uigetdir("Select Root Data Folder");
             app.nameTemplate = app.FileNameTemplate.Value;
        end

        % Value changed function: LowerFrameRange
        function LowerFrameRangeValueChanged(app, event)
            app.frameRange(1) = app.LowerFrameRange.Value;
        end

        % Value changed function: UpperFrameRange
        function UpperFrameRangeValueChanged(app, event)
            app.frameRange(2) = app.UpperFrameRange.Value;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % If frameRange is not specified in the app (i.e. the value is
            % not changed from 0) don't pass it as an input for the parser.
            % If a frameRange is manually specified, pass that input to the
            % parser.
            if app.LowerFrameRange.Value == 0
                app.p = parser(app.fileLocation, app.nameTemplate);
                app.LowerFrameRange.Value = app.p.frameRange(1);
                app.UpperFrameRange.Value = app.p.frameRange(2);
            else
                app.p = parser(app.fileLocation, app.nameTemplate, app.frameRange);
            end

            % Show all loaded images sequentially
            for i = app.p.frameRange(1):app.p.frameRange(2)
                p1 = app.p.file_index(i);
                imshow(p1.frame, 'Parent', app.ImageAxis);
                drawnow;
%                 pause(0.01);
            end

            app.DataLoaded = 1;
        end

        % Value changed function: FileNameTemplate
        function FileNameTemplateValueChanged(app, event)
            app.nameTemplate = app.FileNameTemplate.Value;
        end

        % Button pushed function: DetectCandidatesButton
        function DetectCandidatesButtonPushed(app, event)
            [app.bin, app.gryimg] = candidateDetection(app.p);

            for i = 1:length(app.bin)
                temp = app.bin{i};
                imshow(temp, 'Parent', app.CandidateAxis);
                drawnow;
                pause(0.1);
            end

        end

        % Button pushed function: IdentifyTrueCandidatesButton
        function IdentifyTrueCandidatesButtonPushed(app, event)
            area_threshold = [app.AreaThresholdMin.Value, app.AreaThresholdMax.Value];
            extent_threshold = [app.ExtentThresholdMin.Value, app.ExtentThresholdMax.Value];
            majoraxis_threshold = [app.MajorAxisThresholdMin.Value, app.MajorAxisThresholdMax.Value];
            eccentricity_threshold = [app.EccentricityThresholdMin.Value, app.EccentricityThresholdMax.Value];

            [app.discriminants, centroid, bbox] = candidateDiscrimination(app.bin, app.gryimg, app.p, area_threshold, extent_threshold, majoraxis_threshold, eccentricity_threshold);
            
            for i = 1:length(app.discriminants)
                if isempty(bbox{i})
                    % do nothing
                else 
                    bbox_temp = bbox{i};
                    p2 = app.p.file_index(i+app.frameRange(1)-1);
                    frame_temp = p2.frame;
                    imshow(frame_temp, 'Parent', app.DiscriminationAxis);
                        for j = 1:length(bbox_temp)
                            rectangle(app.DiscriminationAxis,'Position', [bbox_temp(j,1),bbox_temp(j,2),bbox_temp(j,3),bbox_temp(j,4)],'EdgeColor',[1, 0, 0, 0.7],'FaceColor',[0,0,1,0.2],'LineWidth',2);
                        end
                    drawnow;
                    pause(0.5);
                end
            end
        end

        % Button pushed function: LoadSingleFrameButton
        function LoadSingleFrameButtonPushed(app, event)
            % Input: Frame Number within the range specified in frameRange
            % Output: Image of selected frame


            % Check to see if the frame data has been loaded
            if app.DataLoaded == 1
                % Check to see if the chosen frame index is within the
                % frame range
                if (app.SingleFrame.Value > app.p.frameRange(1)) && (app.SingleFrame.Value < app.p.frameRange(2))
                    % Use parser to initialise the frame and the given frame index
                    p2 = app.p.file_index(app.SingleFrame.Value);
                    frame_temp = p2.frame;
                    imshow(frame_temp, 'Parent', app.ImageAxis)
                else
                    errordlg('Selected frame is not within the frame range')
                end
            else 
                errordlg('Dataset had not been loaded')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataLoaderTab = uitab(app.TabGroup);
            app.DataLoaderTab.Title = 'Data Loader';
            app.DataLoadingSettingsPanel = uipanel(app.DataLoaderTab);
            app.DataLoadingSettingsPanel.Title = 'Data Loading Settings';
            app.SelectDataFolderButton = uibutton(app.DataLoadingSettingsPanel, 'push');
            app.SelectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderButtonPushed, true);
            app.SelectDataFolderButton.Text = 'Select Data Folder';
            app.LoadDataButton = uibutton(app.DataLoadingSettingsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LowerFrameRangeEditFieldLabel = uilabel(app.DataLoadingSettingsPanel);
            app.LowerFrameRangeEditFieldLabel.Text = 'Lower Frame Range';
            app.LowerFrameRange = uieditfield(app.DataLoadingSettingsPanel, 'numeric');
            app.LowerFrameRange.ValueChangedFcn = createCallbackFcn(app, @LowerFrameRangeValueChanged, true);
            app.UpperFrameRangeEditFieldLabel = uilabel(app.DataLoadingSettingsPanel);
            app.UpperFrameRangeEditFieldLabel.Text = 'Upper Frame Range';
            app.UpperFrameRange = uieditfield(app.DataLoadingSettingsPanel, 'numeric');
            app.UpperFrameRange.ValueChangedFcn = createCallbackFcn(app, @UpperFrameRangeValueChanged, true);
            app.FileNameTemplateEditFieldLabel = uilabel(app.DataLoadingSettingsPanel);
            app.FileNameTemplateEditFieldLabel.Text = 'File Name Template';
            app.FileNameTemplate = uieditfield(app.DataLoadingSettingsPanel, 'text');
            app.FileNameTemplate.ValueChangedFcn = createCallbackFcn(app, @FileNameTemplateValueChanged, true);
            app.FileNameTemplate.Value = '%06d';
            app.ChoosenumbereddatafoldersetframerangethenclickloaddataLabel = uilabel(app.DataLoadingSettingsPanel);
            app.ChoosenumbereddatafoldersetframerangethenclickloaddataLabel.Text = 'Choose numbered data folder, set frame range, then click load data';
            app.SingleFrame = uieditfield(app.DataLoadingSettingsPanel, 'numeric');
            app.LoadSingleFrameButton = uibutton(app.DataLoadingSettingsPanel, 'push');
            app.LoadSingleFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSingleFrameButtonPushed, true);
            app.LoadSingleFrameButton.Text = 'Load Single Frame';
            app.DataPreviewPanel = uipanel(app.DataLoaderTab);
            app.DataPreviewPanel.Title = 'Data Preview';
            app.ImageAxis = uiaxes(app.DataPreviewPanel);
            app.ImageAxis.XTick = [];
            app.ImageAxis.YTick = [];
            app.ImageAxis.ZTick = [];
            app.CandidateDetectionTab = uitab(app.TabGroup);
            app.CandidateDetectionTab.Title = 'Candidate Detection';
            app.DataLoadingSettingsPanel_2 = uipanel(app.CandidateDetectionTab);
            app.DataLoadingSettingsPanel_2.Title = 'Data Loading Settings';
            app.DetectCandidatesButton = uibutton(app.DataLoadingSettingsPanel_2, 'push');
            app.DetectCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCandidatesButtonPushed, true);
            app.DetectCandidatesButton.Text = 'Detect Candidates';
            app.ClickbuttontoperformcandidatedetectionLabel = uilabel(app.DataLoadingSettingsPanel_2);
            app.ClickbuttontoperformcandidatedetectionLabel.Text = 'Click button to perform candidate detection';
            app.IdentifiedCandidatesPanel = uipanel(app.CandidateDetectionTab);
            app.IdentifiedCandidatesPanel.Title = 'Identified Candidates';
            app.CandidateAxis = uiaxes(app.IdentifiedCandidatesPanel);
            app.CandidateAxis.XTick = [];
            app.CandidateAxis.YTick = [];
            app.CandidateAxis.ZTick = [];
            app.CandidateDiscriminationTab = uitab(app.TabGroup);
            app.CandidateDiscriminationTab.Title = 'Candidate Discrimination';
            app.SettingsPanel = uipanel(app.CandidateDiscriminationTab);
            app.SettingsPanel.Title = 'Settings';
            app.IdentifyTrueCandidatesButton = uibutton(app.SettingsPanel, 'push');
            app.IdentifyTrueCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyTrueCandidatesButtonPushed, true);
            app.IdentifyTrueCandidatesButton.Text = 'Identify True Candidates';
            app.AreaLabel = uilabel(app.SettingsPanel);
            app.AreaLabel.Text = 'Area';
            app.AreaThresholdMin = uieditfield(app.SettingsPanel, 'numeric');
            app.AreaThresholdMin.Limits = [1 50];
            app.AreaThresholdMin.Value = 1;
            app.AreaThresholdMax = uieditfield(app.SettingsPanel, 'numeric');
            app.AreaThresholdMax.Limits = [50 200];
            app.AreaThresholdMax.Value = 100;
            app.ExtentThresholdLabel = uilabel(app.SettingsPanel);
            app.ExtentThresholdLabel.Text = 'Extent';
            app.ExtentThresholdMin = uieditfield(app.SettingsPanel, 'numeric');
            app.ExtentThresholdMin.Limits = [0.1 0.5];
            app.ExtentThresholdMin.Value = 0.4;
            app.ExtentThresholdMax = uieditfield(app.SettingsPanel, 'numeric');
            app.ExtentThresholdMax.Limits = [0.5 1];
            app.ExtentThresholdMax.Value = 1;
            app.MajorAxisThresholdLabel = uilabel(app.SettingsPanel);
            app.MajorAxisThresholdLabel.Text = 'Major Axis';
            app.MajorAxisThresholdMin = uieditfield(app.SettingsPanel, 'numeric');
            app.MajorAxisThresholdMin.Limits = [0 10];
            app.MajorAxisThresholdMin.Value = 5;
            app.MajorAxisThresholdMax = uieditfield(app.SettingsPanel, 'numeric');
            app.MajorAxisThresholdMax.Limits = [10 30];
            app.MajorAxisThresholdMax.Value = 20;
            app.ExxentricityLabel = uilabel(app.SettingsPanel);
            app.ExxentricityLabel.Text = 'Eccentricity';
            app.EccentricityThresholdMin = uieditfield(app.SettingsPanel, 'numeric');
            app.EccentricityThresholdMin.Limits = [0.1 0.5];
            app.EccentricityThresholdMin.Value = 0.2;
            app.EccentricityThresholdMax = uieditfield(app.SettingsPanel, 'numeric');
            app.EccentricityThresholdMax.Limits = [0.5 1];
            app.EccentricityThresholdMax.Value = 0.8;
            app.DiscriminationLabel = uilabel(app.SettingsPanel);
            app.DiscriminationLabel.Text = 'Apply thresholds and click button';
            app.IdentifiedVehiclesPanel = uipanel(app.CandidateDiscriminationTab);
            app.IdentifiedVehiclesPanel.Title = 'Identified Vehicles';
            app.DiscriminationAxis = uiaxes(app.IdentifiedVehiclesPanel);
            app.DiscriminationAxis.XTick = [];
            app.DiscriminationAxis.YTick = [];
            app.DiscriminationAxis.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end